classdef Reconstruction_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ReconstructImageButton     matlab.ui.control.Button
        SaveImageButton            matlab.ui.control.Button
        SelectReconstructionMethodListBox  matlab.ui.control.ListBox
        ReconstructionMethodLabel  matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        CurrentFileTextArea        matlab.ui.control.TextArea
        CurrentFileTextAreaLabel   matlab.ui.control.Label
        ReconstructedImagePanel    matlab.ui.container.Panel
        OpenFileButton             matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        CSCameraReconstructionSoftwareLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        VerboseMode logical = true; % Set to false to silence console prints
        LoadedData
    end
    
    methods (Access = private)
        % Only print to the console if VerboseMode is turned on
        function logMsg(app, inputData)
            if app.VerboseMode
                % Check if the input is text (character vector or string)
                if ischar(inputData) || isstring(inputData)
                    disp(['[DEBUG] ', char(inputData)]);
                else
                    % If it is a table or matrix, print it on its own line
                    disp('[DEBUG] Data output:');
                    disp(inputData);
                end
            end
        end

        % Helper function to format and display images on the UI
        function displayReconstructedImage(app, imgMatrix)
            % Check if the matrix is a flat 2D grayscale image
            if ismatrix(imgMatrix) 
                % Convert to m-by-n-by-3 RGB array for the UI component
                rgbImage = repmat(imgMatrix, [1, 1, 3]);
            else
                rgbImage = imgMatrix; 
            end
            
            % Update the image component
            app.Image2.ImageSource = rgbImage;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set window size to 1024 pixels wide by 768 pixels tall
            app.UIFigure.Position = [100, 100, 1024, 768];
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            
            % Open the file dialog
            [file, path] = uigetfile({'*.csv', 'CSV Files'; ...
                                      '*.*', 'All Files'}, 'Select a CSV File');
            
            % Restore window to top of view
            figure(app.UIFigure);

            % Check if the user cancels/closes file select window.
            if isequal(file, 0)
                return;
            end

            fullFileName = fullfile(path, file);

            % Update textbox with filename
            app.CurrentFileTextArea.Value = fullFileName;

            try
                % Read as a matrix since the math function needs a dataMatrix
                app.LoadedData = readmatrix(fullFileName);
                
                % 1. Log the text message
                app.logMsg('Successfully loaded file. Previewing data:'); 
                
                % 2. Log a 5x5 snippet of the data matrix to the console
                app.logMsg(app.LoadedData(1:min(5, end), 1:min(5, end))); 
                
                % 3. Wake up the Reconstruct button now that we have data!
                app.ReconstructImageButton.Enable = 'on';
                
            catch ME
                uialert(app.UIFigure, ['Error reading CSV: ', ME.message], 'Import Error')
            end
        end

        % Button pushed function: ReconstructImageButton
        function ReconstructImageButtonPushed(app, event)
            % 1. Check if a file is actually loaded before running
            if isempty(app.CurrentFileTextArea.Value)
                uialert(app.UIFigure, 'Please open a CSV file first.', 'Missing Data');
                return;
            end
        
            % Disable button, change text, and set a loading cursor to prevent spamming
            app.ReconstructImageButton.Enable = 'off';
            app.ReconstructImageButton.Text = 'Processing...';
            app.UIFigure.Pointer = 'watch'; 
            
            % Force the UI to visually update right now before the heavy math starts
            drawnow; 
            
            try
                % 2. Get the currently selected method from the list box
                selectedMethod = app.SelectReconstructionMethodListBox.Value;
                app.logMsg(['Starting reconstruction using: ', selectedMethod]);
                
                % 3. Route to the correct algorithm
                switch selectedMethod
                    case 'Inverse Transform'
                        app.logMsg('Executing Inverse Transform...');
                        
                        % Run external function using the stored data
                        reconstructedImg = reco.simpleReconstruction(app.LoadedData);
                        
                        app.displayReconstructedImage(reconstructedImg);
                        
                    case 'Total Variance'
                        app.logMsg('Executing Total Variance...');
                        
                        % Run external function using the stored data
                        reconstructedImg = reco.tvReconstruction(app.LoadedData);
                        
                        app.displayReconstructedImage(reconstructedImg);
                        
                    case 'CNN'
                        % TODO: Add CNN code here
                        app.logMsg('Executing CNN...');
                        pause(2); % Fake delay to test the loading UI (delete later!)
                end
                
            app.SaveImageButton.Enable = 'on';

            catch ME
                % If the math crashes, show an error message
                uialert(app.UIFigure, ['Reconstruction failed: ', ME.message], 'Error');
            end
            
            % Always restore the button and cursor, whether it succeeded or failed!
            app.ReconstructImageButton.Text = 'Reconstruct Image';
            app.ReconstructImageButton.Enable = 'on';
            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % 1. Safety check: Double confirm there is an image
            if isempty(app.Image2.ImageSource)
                uialert(app.UIFigure, 'No image available to save.', 'Save Error');
                return;
            end
            
            % Grab the text area value. If it's a cell array, extract the first line using {1}
            currentFilePath = app.CurrentFileTextArea.Value;
            if iscell(currentFilePath)
                currentFilePath = currentFilePath{1};
            end
            
            % Extract the base name
            [~, baseName, ~] = fileparts(currentFilePath);
            
            % Do the exact same extraction for the List Box value
            methodName = app.SelectReconstructionMethodListBox.Value;
            if iscell(methodName)
                methodName = methodName{1};
            end
            
            % Convert both strictly to characters and replace spaces with underscores
            baseName = char(baseName);
            safeMethodName = strrep(char(methodName), ' ', '_');
            
            % Combine them to create the default save name
            defaultFileName = sprintf('%s_%s.png', baseName, safeMethodName);
            
            % 2. Open the 'Save As' dialog with ONLY lossless formats
            [file, path] = uiputfile({'*.png', 'PNG Image (*.png)'; ...
                                      '*.tif;*.tiff', 'TIFF Image (*.tif, *.tiff)'; ...
                                      '*.bmp', 'Bitmap Image (*.bmp)'}, ...
                                      'Save Reconstructed Image', defaultFileName);
            
            % 3. Check if the user clicked 'Cancel' or closed the window
            if isequal(file, 0) || isequal(path, 0)
                return; 
            end
            
            % 4. Construct the final file path
            fullFileName = fullfile(path, file);
            
            % 5. Attempt to save the image to the drive
            try
                % Grab the matrix currently displayed on the UI
                imgData = app.Image2.ImageSource;
                
                % imwrite automatically detects the format from file extension
                imwrite(imgData, fullFileName);
                
                % Log the success to the console
                app.logMsg(['Image successfully saved to: ', fullFileName]);
                
            catch ME
                % If something goes wrong (e.g., folder requires admin privileges)
                uialert(app.UIFigure, ['Failed to save image: ', ME.message], 'Save Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CSCameraReconstructionSoftwareLabel = uilabel(app.GridLayout);
            app.CSCameraReconstructionSoftwareLabel.Text = 'CSCamera Reconstruction Software';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'eng_logo.png');
            app.OpenFileButton = uibutton(app.GridLayout, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.ReconstructedImagePanel = uipanel(app.GridLayout);
            app.ReconstructedImagePanel.Title = 'Reconstructed Image:';
            app.CurrentFileTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentFileTextAreaLabel.Text = 'Current File:';
            app.CurrentFileTextArea = uitextarea(app.GridLayout);
            app.CurrentFileTextArea.Editable = 'off';
            app.Image2 = uiimage(app.GridLayout);
            app.ReconstructionMethodLabel = uilabel(app.GridLayout);
            app.ReconstructionMethodLabel.Text = 'Select Reconstruction Method:';
            app.SelectReconstructionMethodListBox = uilistbox(app.GridLayout);
            app.SelectReconstructionMethodListBox.Items = {'Inverse Transform', 'Total Variance', 'CNN'};
            app.SelectReconstructionMethodListBox.Value = 'Inverse Transform';
            app.SaveImageButton = uibutton(app.GridLayout, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.ReconstructImageButton = uibutton(app.GridLayout, 'push');
            app.ReconstructImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructImageButtonPushed, true);
            app.ReconstructImageButton.Enable = 'off';
            app.ReconstructImageButton.Text = 'Reconstruct Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reconstruction_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end